classdef DesignProjectSerialInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Gauge                    matlab.ui.control.LinearGauge
        Label                    matlab.ui.control.Label
        CurrentReadingLabel      matlab.ui.control.Label
        SetpointCEditField       matlab.ui.control.NumericEditField
        SetpointCEditFieldLabel  matlab.ui.control.Label
        RunSwitch                matlab.ui.control.ToggleSwitch
        RunSwitchLabel           matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = public)
        vPOINTS % Number of voltage datapoints recieved from MSP430 at a single time to be averaged
        NPOINTS % Maximum number of datapoints to simultaneously display on screen
        tSample % Sampling delay
        tArray % Array of times that samples were taken
        TempArray % Array of temperature samples
        FirstRun % Boolean value to conditionally perform tasks on the first run
        SampleTime % Total sampling interval
        BaudRate % Baud rate
        V_ref % Reference voltage
        bits % Resolution of unsigned 8-bit value
        R_ref % Reference resistance of fixed resistor
        T_ref % Reference maximum possible temperature error
        bit_ref % Reference bit resolution of signed 8-bit value
        error % Temperature error
        last_error % Previous temperature error
        sum_error % Total sum of temperature errors
        K_p % Proportional gain
        K_i % Integral gain
        K_d % Derivative gain
        readingText % Placeholder text for readout label
        comport % Serial port
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set all properties to their initial values on startup
            app.vPOINTS = 30;
            app.NPOINTS = 100;
            app.tSample = 0.1;
            app.tArray = [];
            app.TempArray = [];
            app.FirstRun = true;
            app.BaudRate = 115200;
            app.SampleTime = app.tSample + 480/app.BaudRate; % 480 comes from 8 bits * 2 ways * 30 datapoints
            app.V_ref = 3.3;
            app.bits = 255;
            app.R_ref = 9.994e3;
            app.T_ref = 45;
            app.bit_ref = 127;
            app.error = 0.0;
            app.last_error = 0.0;
            app.sum_error = 0.0;
            app.K_p = 15.0;
            app.K_i = 0.2;
            app.K_d = 5.5;
            app.readingText = "Current Reading (°C): ";

            % Set app UI properties for clean presentation
            app.UIAxes.YLim = [0, 50];
            app.RunSwitch.Value = "Off";

            app.comport = serialport('COM12', app.BaudRate); % Initialize serial connection

            write(app.comport, 0, 'int8') % Send character to initialize communication
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.comport = []; % Close serial connection

            delete(app)
        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
            UpdateView = app.RunSwitch.Value; % Get switch position

            switch UpdateView % Run program or remain dormant depending on switch position
                case "On"
                    pause(app.tSample) % Wait for sampling delay

                    set_temp = app.SetpointCEditField.Value; % Get setpoint from user

                    duty_cycle = app.error*app.K_p/app.T_ref*app.bit_ref + app.sum_error*app.K_i/app.T_ref*app.bit_ref + (app.error - app.last_error)/app.SampleTime*app.K_d/app.T_ref*app.bit_ref; % Obtain desired duty cycle as output of PID controller
                    adj_duty_cycle = max(min(round(duty_cycle), app.bit_ref - 1), -app.bit_ref + 1); % Clamping the duty cycle to fit within the limits of an 8-bit signed integer

                    write(app.comport, adj_duty_cycle, 'int8') % Write duty cycle as signed 8-bit value to serial connection (negative = cooling, positive = heating)

                    V_read = sum(read(app.comport, app.vPOINTS, 'uint8'))/(app.bits*app.vPOINTS)*app.V_ref; % Read ADC output over serial connection, average as float, and convert to voltage
                    R_tec = V_read*app.R_ref/(app.V_ref - V_read); % Convert voltage reading to resistance of thermistor
                    Temp = -1.0403*log(R_tec/30.374e3)/0.044 - 0.2649; % Convert resistance reading to temperature of Peltier module

                    app.last_error = app.error; % Save previous temperature error
                    app.error = set_temp - Temp; % Update current temperature error
                    app.sum_error = app.sum_error + nthroot(app.error, 3); % Update total sum of temperature error

                    if app.FirstRun % Run conditionally on first run unless reset
                        app.tArray(end + 1) = 0; % Start time at reference 0
                        app.FirstRun = false;
                    else
                        if length(app.tArray) > app.NPOINTS % Limit live temperature feed to window of size NPOINTS
                            app.tArray(1) = [];
                            app.TempArray(1) = [];
                        end
    
                        app.tArray(end + 1) = app.tArray(end) + app.SampleTime; % Update time array
                    end

                    app.TempArray(end + 1) = Temp; % Update temperature array
    
                    app.CurrentReadingLabel.Text = app.readingText + num2str(Temp, 4); % Update current reading output
                    app.Gauge.Value = Temp; % Update guage reading

                    plot(app.UIAxes, app.TempArray, 'XData', app.tArray, Color='Blue') % Plot data on live window
                    hold(app.UIAxes, 'on')
                    plot(app.UIAxes, [app.tArray(1), app.tArray(end)], [set_temp, set_temp], LineWidth=2, Color='Red') % Plot setpoint on live window
                    hold(app.UIAxes, 'off')

                    app.RunSwitchValueChanged(); % Recursively re-run function to continuously update
                case "Off"
                    % Reset all variables to initial values
                    app.tArray = [];
                    app.TempArray = [];
                    app.error = 0.0;
                    app.last_error = 0.0;
                    app.sum_error = 0.0;
                    app.CurrentReadingLabel.Text = app.readingText + "N/A";
                    app.FirstRun = true;

                    write(app.comport, 0, 'int8') % Turn off PWM output to bring Peltier module back to room temperature
            end
        end

        % Value changed function: SetpointCEditField
        function SetpointCEditFieldValueChanged(app, event)
            app.sum_error = 0; % Reset integral error if the setpoint changes for faster convergence
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Live Temperature View')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Temperature (°C)')
            zlabel(app.UIAxes, 'Z')
            app.RunSwitchLabel = uilabel(app.UIFigure);
            app.RunSwitchLabel.Text = 'Run';
            app.RunSwitch = uiswitch(app.UIFigure, 'toggle');
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.SetpointCEditFieldLabel = uilabel(app.UIFigure);
            app.SetpointCEditFieldLabel.Text = 'Setpoint (°C)';
            app.SetpointCEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetpointCEditField.Limits = [5 45];
            app.SetpointCEditField.ValueChangedFcn = createCallbackFcn(app, @SetpointCEditFieldValueChanged, true);
            app.SetpointCEditField.Value = 25;
            app.CurrentReadingLabel = uilabel(app.UIFigure);
            app.CurrentReadingLabel.Text = 'Current Reading (°C): N/A';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.Gauge.Limits = [0 50];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DesignProjectSerialInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end